classdef sudoku < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        solvesudo     matlab.ui.control.Button
        readsudo      matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        GridLayout    matlab.ui.container.GridLayout
        EditField     matlab.ui.control.EditField
        EditField_2   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        EditField_4   matlab.ui.control.EditField
        EditField_5   matlab.ui.control.EditField
        EditField_6   matlab.ui.control.EditField
        EditField_7   matlab.ui.control.EditField
        EditField_8   matlab.ui.control.EditField
        EditField_9   matlab.ui.control.EditField
        EditField_10  matlab.ui.control.EditField
        EditField_11  matlab.ui.control.EditField
        EditField_12  matlab.ui.control.EditField
        EditField_13  matlab.ui.control.EditField
        EditField_14  matlab.ui.control.EditField
        EditField_15  matlab.ui.control.EditField
        EditField_16  matlab.ui.control.EditField
        EditField_17  matlab.ui.control.EditField
        EditField_18  matlab.ui.control.EditField
        EditField_19  matlab.ui.control.EditField
        EditField_20  matlab.ui.control.EditField
        EditField_21  matlab.ui.control.EditField
        EditField_22  matlab.ui.control.EditField
        EditField_23  matlab.ui.control.EditField
        EditField_24  matlab.ui.control.EditField
        EditField_25  matlab.ui.control.EditField
        EditField_26  matlab.ui.control.EditField
        EditField_27  matlab.ui.control.EditField
        EditField_28  matlab.ui.control.EditField
        EditField_29  matlab.ui.control.EditField
        EditField_30  matlab.ui.control.EditField
        EditField_31  matlab.ui.control.EditField
        EditField_32  matlab.ui.control.EditField
        EditField_33  matlab.ui.control.EditField
        EditField_34  matlab.ui.control.EditField
        EditField_35  matlab.ui.control.EditField
        EditField_36  matlab.ui.control.EditField
        EditField_37  matlab.ui.control.EditField
        EditField_38  matlab.ui.control.EditField
        EditField_39  matlab.ui.control.EditField
        EditField_40  matlab.ui.control.EditField
        EditField_41  matlab.ui.control.EditField
        EditField_42  matlab.ui.control.EditField
        EditField_43  matlab.ui.control.EditField
        EditField_44  matlab.ui.control.EditField
        EditField_45  matlab.ui.control.EditField
        EditField_46  matlab.ui.control.EditField
        EditField_47  matlab.ui.control.EditField
        EditField_48  matlab.ui.control.EditField
        EditField_49  matlab.ui.control.EditField
        EditField_50  matlab.ui.control.EditField
        EditField_51  matlab.ui.control.EditField
        EditField_52  matlab.ui.control.EditField
        EditField_53  matlab.ui.control.EditField
        EditField_54  matlab.ui.control.EditField
        EditField_55  matlab.ui.control.EditField
        EditField_56  matlab.ui.control.EditField
        EditField_57  matlab.ui.control.EditField
        EditField_58  matlab.ui.control.EditField
        EditField_59  matlab.ui.control.EditField
        EditField_60  matlab.ui.control.EditField
        EditField_61  matlab.ui.control.EditField
        EditField_62  matlab.ui.control.EditField
        EditField_63  matlab.ui.control.EditField
        EditField_64  matlab.ui.control.EditField
        EditField_65  matlab.ui.control.EditField
        EditField_66  matlab.ui.control.EditField
        EditField_67  matlab.ui.control.EditField
        EditField_68  matlab.ui.control.EditField
        EditField_69  matlab.ui.control.EditField
        EditField_70  matlab.ui.control.EditField
        EditField_71  matlab.ui.control.EditField
        EditField_72  matlab.ui.control.EditField
        EditField_73  matlab.ui.control.EditField
        EditField_74  matlab.ui.control.EditField
        EditField_75  matlab.ui.control.EditField
        EditField_76  matlab.ui.control.EditField
        EditField_77  matlab.ui.control.EditField
        EditField_78  matlab.ui.control.EditField
        EditField_79  matlab.ui.control.EditField
        EditField_80  matlab.ui.control.EditField
        EditField_81  matlab.ui.control.EditField
        ButtonGroup   matlab.ui.container.ButtonGroup
        easy          matlab.ui.control.RadioButton
        medium        matlab.ui.control.RadioButton
        hard          matlab.ui.control.RadioButton
        Button        matlab.ui.control.Button
    end

    
    properties (Access = private)
        B % Description
        S
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: readsudo
        function readsudoPushed(app, event)
            clear app.B;
            h1=app.GridLayout.Children;
            set(h1,'value','');
            set(h1,'enable','On');
            set(h1,'fontcolor','k');
            
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case '简单'
                    value ='easy';
                case '中等'
                    value ='medium';
                case '困难'
                    value ='hard';
            end
            
            app.B=generate_sudoku(value);
            app.S = sudokuEngine(app.B);
            for i=1:size(app.B,1)
                
                h=app.GridLayout.Children((app.B(i,1)-1)*9+app.B(i,2));
                set(h,'value',num2str(app.B(i,3)));
                set(h,'enable','Off');
                set(h,'fontcolor','k')
                
            end


        end

        % Button pushed function: solvesudo
        function solvesudoButtonPushed(app, event)


            for i=1:size(app.S,1)
                
                for j=1:size(app.S,2)
                    
                    h=app.GridLayout.Children((i-1)*9+j);
                    set(h,'value',num2str(app.S(i,j)));
                    
                end
                
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            U=app.S';
            
            % 对比每个文本框的答案与正确答案
            for i = 1:numel(app.GridLayout.Children)
                
                % 获取当前文本框的答案
                if app.GridLayout.Children(i).Enable==1
                    
                    userAnswer = num2str(app.GridLayout.Children(i).Value);
                    
                    % 获取当前文本框对应的正确答案
                    correctAnswer = num2str(U(i)); % 自定义函数，根据格子的索引获取正确答案
                    
                    % 对比用户输入的答案与正确答案
                    if strcmp(userAnswer, correctAnswer)
                        % 答案正确
                        app.GridLayout.Children(i).FontColor = 'g';
                    else
                        % 答案错误
                        app.GridLayout.Children(i).FontColor = 'r';
                    end
                else
                    continue
                    
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '简单的数独生成器';
            app.UIFigure.Icon = '数独游戏.png';
            app.solvesudo = uibutton(app.UIFigure, 'push');
            app.solvesudo.ButtonPushedFcn = createCallbackFcn(app, @solvesudoButtonPushed, true);
            app.solvesudo.Text = '查看答案';
            app.readsudo = uibutton(app.UIFigure, 'push');
            app.readsudo.ButtonPushedFcn = createCallbackFcn(app, @readsudoPushed, true);
            app.readsudo.Text = '生成数独';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数独';
            app.GridLayout = uigridlayout(app.Panel);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_3 = uieditfield(app.GridLayout, 'text');
            app.EditField_4 = uieditfield(app.GridLayout, 'text');
            app.EditField_5 = uieditfield(app.GridLayout, 'text');
            app.EditField_6 = uieditfield(app.GridLayout, 'text');
            app.EditField_7 = uieditfield(app.GridLayout, 'text');
            app.EditField_8 = uieditfield(app.GridLayout, 'text');
            app.EditField_9 = uieditfield(app.GridLayout, 'text');
            app.EditField_10 = uieditfield(app.GridLayout, 'text');
            app.EditField_11 = uieditfield(app.GridLayout, 'text');
            app.EditField_12 = uieditfield(app.GridLayout, 'text');
            app.EditField_13 = uieditfield(app.GridLayout, 'text');
            app.EditField_14 = uieditfield(app.GridLayout, 'text');
            app.EditField_15 = uieditfield(app.GridLayout, 'text');
            app.EditField_16 = uieditfield(app.GridLayout, 'text');
            app.EditField_17 = uieditfield(app.GridLayout, 'text');
            app.EditField_18 = uieditfield(app.GridLayout, 'text');
            app.EditField_19 = uieditfield(app.GridLayout, 'text');
            app.EditField_20 = uieditfield(app.GridLayout, 'text');
            app.EditField_21 = uieditfield(app.GridLayout, 'text');
            app.EditField_22 = uieditfield(app.GridLayout, 'text');
            app.EditField_23 = uieditfield(app.GridLayout, 'text');
            app.EditField_24 = uieditfield(app.GridLayout, 'text');
            app.EditField_25 = uieditfield(app.GridLayout, 'text');
            app.EditField_26 = uieditfield(app.GridLayout, 'text');
            app.EditField_27 = uieditfield(app.GridLayout, 'text');
            app.EditField_28 = uieditfield(app.GridLayout, 'text');
            app.EditField_29 = uieditfield(app.GridLayout, 'text');
            app.EditField_30 = uieditfield(app.GridLayout, 'text');
            app.EditField_31 = uieditfield(app.GridLayout, 'text');
            app.EditField_32 = uieditfield(app.GridLayout, 'text');
            app.EditField_33 = uieditfield(app.GridLayout, 'text');
            app.EditField_34 = uieditfield(app.GridLayout, 'text');
            app.EditField_35 = uieditfield(app.GridLayout, 'text');
            app.EditField_36 = uieditfield(app.GridLayout, 'text');
            app.EditField_37 = uieditfield(app.GridLayout, 'text');
            app.EditField_38 = uieditfield(app.GridLayout, 'text');
            app.EditField_39 = uieditfield(app.GridLayout, 'text');
            app.EditField_40 = uieditfield(app.GridLayout, 'text');
            app.EditField_41 = uieditfield(app.GridLayout, 'text');
            app.EditField_42 = uieditfield(app.GridLayout, 'text');
            app.EditField_43 = uieditfield(app.GridLayout, 'text');
            app.EditField_44 = uieditfield(app.GridLayout, 'text');
            app.EditField_45 = uieditfield(app.GridLayout, 'text');
            app.EditField_46 = uieditfield(app.GridLayout, 'text');
            app.EditField_47 = uieditfield(app.GridLayout, 'text');
            app.EditField_48 = uieditfield(app.GridLayout, 'text');
            app.EditField_49 = uieditfield(app.GridLayout, 'text');
            app.EditField_50 = uieditfield(app.GridLayout, 'text');
            app.EditField_51 = uieditfield(app.GridLayout, 'text');
            app.EditField_52 = uieditfield(app.GridLayout, 'text');
            app.EditField_53 = uieditfield(app.GridLayout, 'text');
            app.EditField_54 = uieditfield(app.GridLayout, 'text');
            app.EditField_55 = uieditfield(app.GridLayout, 'text');
            app.EditField_56 = uieditfield(app.GridLayout, 'text');
            app.EditField_57 = uieditfield(app.GridLayout, 'text');
            app.EditField_58 = uieditfield(app.GridLayout, 'text');
            app.EditField_59 = uieditfield(app.GridLayout, 'text');
            app.EditField_60 = uieditfield(app.GridLayout, 'text');
            app.EditField_61 = uieditfield(app.GridLayout, 'text');
            app.EditField_62 = uieditfield(app.GridLayout, 'text');
            app.EditField_63 = uieditfield(app.GridLayout, 'text');
            app.EditField_64 = uieditfield(app.GridLayout, 'text');
            app.EditField_65 = uieditfield(app.GridLayout, 'text');
            app.EditField_66 = uieditfield(app.GridLayout, 'text');
            app.EditField_67 = uieditfield(app.GridLayout, 'text');
            app.EditField_68 = uieditfield(app.GridLayout, 'text');
            app.EditField_69 = uieditfield(app.GridLayout, 'text');
            app.EditField_70 = uieditfield(app.GridLayout, 'text');
            app.EditField_71 = uieditfield(app.GridLayout, 'text');
            app.EditField_72 = uieditfield(app.GridLayout, 'text');
            app.EditField_73 = uieditfield(app.GridLayout, 'text');
            app.EditField_74 = uieditfield(app.GridLayout, 'text');
            app.EditField_75 = uieditfield(app.GridLayout, 'text');
            app.EditField_76 = uieditfield(app.GridLayout, 'text');
            app.EditField_77 = uieditfield(app.GridLayout, 'text');
            app.EditField_78 = uieditfield(app.GridLayout, 'text');
            app.EditField_79 = uieditfield(app.GridLayout, 'text');
            app.EditField_80 = uieditfield(app.GridLayout, 'text');
            app.EditField_81 = uieditfield(app.GridLayout, 'text');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '选择难度';
            app.easy = uiradiobutton(app.ButtonGroup);
            app.easy.Text = '简单';
            app.medium = uiradiobutton(app.ButtonGroup);
            app.medium.Text = '中等';
            app.hard = uiradiobutton(app.ButtonGroup);
            app.hard.Text = '困难';
            app.hard.Value = true;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '检查答案';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sudoku

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end